classdef Ass_3_code < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunButton                       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        MinimumCostGaugeLabel           matlab.ui.control.Label
        Gauge                           matlab.ui.control.Gauge
        UIAxes2                         matlab.ui.control.UIAxes
        Num_EpochsDropDownLabel         matlab.ui.control.Label
        Num_EpochsDropDown              matlab.ui.control.DropDown
        MutationProbabilitySliderLabel  matlab.ui.control.Label
        MutationProbabilitySlider       matlab.ui.control.Slider
        LearningSpeedButtonGroup        matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button_3                        matlab.ui.control.RadioButton
        EditField                       matlab.ui.control.EditField
        MutationsLabel                  matlab.ui.control.Label
        MutationsEditField              matlab.ui.control.NumericEditField
        IterationsLabel                 matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        InfeasibilitiesEditFieldLabel   matlab.ui.control.Label
        InfeasibilitiesEditField        matlab.ui.control.NumericEditField
        MinimumCostEditFieldLabel       matlab.ui.control.Label
        MinimumCostEditField            matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %Read the cost table
            data = readtable('Solutions.xlsx', 'Range', 'C2:N10');
            
            app.UITable.Data = data;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
           
            clear all          % Clear workspace
            clc;               % Clear terminal window                     


            %==========================================================================
            % Initialisation - User Adjustable Variables
            %==========================================================================
            
            
            num_epochs = app.Num_EpochsDropDown.Value;
            
            %How much to adjust the probability vector
            learning_speed = sum( [app.Button.Value, app.Button_2.Value, app.Button_3.Value] ...
                             .*[0.05, 0.01, 0.005 ]); 
            
            %Maximum number of connections to a terminal
            constraint = 3;       
            
            %The probability a bit will change fpr the best epoch
            prob_mutate = app.MutationProbabilitySlider.Value;   
            
            max_trials = 500;      
            
 
            %==========================================================================
            % Initialisation - Static Variables
            %==========================================================================

            num_terminals = 12;     
            num_concentrators = 8; 
            bit_length = 3;         % Number of bits needed to address concentrators
            vector_length = num_terminals * bit_length; % Length of bit string
            eps = 0.05;             % To check if floating pont is == 0 or 1 


            %==========================================================================
            % Initialisation - Counters
            %==========================================================================

            mutation_count = 0;   
            trials = 1;             
            illegal_count = 0;    
                     

            % Initial probability vector is a 1x36 array
            % with all elements set to 0.5 (ie a 50/50 probability).
            prob_vec = ones(1, vector_length) .* 0.5;
            
            % Array to hold the minimum cost from each trial
            best_best = [];


            %==========================================================================
            % Run Trials
            %==========================================================================
      
            while(~all((prob_vec + eps >= 1) | (prob_vec - eps <= 0)) & (trials < max_trials)) 
                
                
                % Create a 2D array of bit strings based on the current state of the 
                % probability vector
                epoch = ( rand(str2num(num_epochs), vector_length) >= prob_vec );
                
                % For each row "z" in epoch..
                for z = 1:str2num(num_epochs)
                    
                    % Generate a new connection matrix (used to test constraints)
                    con_mat = zeros(num_concentrators, num_terminals);
                    
                    % Convert bit string into a (bit_length x num_terminals) array
                    trialx = reshape(epoch(z,:), bit_length, num_terminals);
            
                    % Convert each COLUMN into a decimal number
                    % This decimal is the "column" lookup in the Cost Table
                    for i = 1:num_terminals
                        
                        % polyval() is used for bin to dec conversion
                        % then add one to get "1" base count for matlab arrays
                        conc_address(i) = polyval(trialx(:,i), 2) + 1; 
                        
                    end 
                   
                    % Find the link cost for each (concentrator, terminal) pair
                    % by indexing the CostTable with [conc_address(i), terminal]
                    for i = 1:num_terminals
                        
                        link_costs(i) = app.UITable.Data{conc_address(i), i};
                        
                        % Update connection matrix
                        con_mat(conc_address(i), i) = 1;
                        
                    end 
                    
                    % Check that bit string obeys terminals per concentrator constraint
                    
                    illegal = 0; % Flag to indicate bad string
                    
                    % For every concentrator row...
                    for i = 1:num_concentrators
                        
                        % If concentrator has more than "constraint" connections...    
                        if(sum(con_mat(i,:)) > constraint)
                            
                            % Set a flag to indicate this row fails constraint
                            illegal = illegal + 1;
                            
                            % Keep a count of how many bad bit strings found
                            illegal_count = illegal_count + 1;
                            
                        end 
                        
                    end 
                    
                    % If constraint check fails...
                    if(illegal >= 1)
                        
                        % Set this bit strings link cost to NaN (invalid)
                        this_trial(z) = NaN;
                        
                    % Otherwise constraint is met (bit string is good)...
                    else
                        
                        % Calculate the cost of this bit string (sum of all links)
                        this_trial(z) = sum(link_costs);
                        
                        % Store the latest good bit string
                        latest_good = conc_address;
                        
                    end 
            
                end
            
                % Find the index of the least cost bit string from this epoch
                [min_val, min_index] = min(this_trial);
                 
                % Mutate the prototype bit string based on probability "prob_mutate"
                if(rand() < prob_mutate)       
                    
                    % Flip a random bit in the bit string
                    epoch(min_index, randi(vector_length)) = ~epoch(min_index, randi(vector_length));     
                    
                    % Keep track of how many mutations have occured
                    mutation_count = mutation_count + 1;
                    
                end 
                
            
                % Update the probability vector
                for j = 1:vector_length
                    
                    % If epoch element "j" is a "1" then make "1's" more likeley...
                    if(epoch(min_index, j))
                        
                        % Make sure probability values stay between 0 and 1
                        if((prob_vec(j) - learning_speed) >= 0)
                            
                            prob_vec(j) = prob_vec(j) - learning_speed;
                            
                        end % End of IF
                        
                    % Otherwise epoch element "j" is a "0" then make "0's" more likely
                    else
                        
                        % Make sure probability values stay between 0 and 1
                        if((prob_vec(j) + learning_speed) <= 1)
                            
                            prob_vec(j) = prob_vec(j) + learning_speed;
                            
                        end 
                        
                    end 
                    
                end 
            
                % Add the minimum trial cost to array of all trial minimums
                best_best(trials) = min_val;
                
               
                % Keep track of how many trials have been run
                trials = trials + 1;
                
            end 
            
            
            %==========================================================================
            % Display Statistics
            %==========================================================================
            fprintf('| Trials run: %d\t\t| Minimum Cost: %d\t\t|\n', trials, min(best_best));
            fprintf('| Mutation Events: %d\t\t| Outside Constraints: %d\t|\n', ... 
                    mutation_count, illegal_count);
            
                    
            app.MutationsEditField.Value = mutation_count;
            
            app.InfeasibilitiesEditField.Value = illegal_count;
            
            app.IterationsEditField.Value = trials;
            
            app.MinimumCostEditField.Value = min(best_best);
            
            %==========================================================================
            % Plot Graphs
            %==========================================================================
            plot(app.UIAxes, best_best);           
          
            
            x = 1:num_terminals;
            y = latest_good;
            
           
            for i = 1:num_terminals
                
                col = rand(1,3);    
                plot(app.UIAxes2, [i i], [0 y(i)], 'LineWidth', 2,'Color', col); 
                plot(app.UIAxes2, [0 i], [y(i) y(i)], 'LineWidth', 2,'Color', col);   
                
            end 
            
            scatter(app.UIAxes2, x, y,'rs', 'MarkerFaceColor', 'r'); 

            %Show the cost for the optimal solution found
            app.Gauge.Value = min(best_best);
            

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
            
            % Allow the user to change data
            indices = event.Indices;
            newData = event.NewData;
            
            % If cost entered is invalid(not positive) the change is prevented
            if(newData<=0)
                app.UITable.Data{indices(1), indices(2)} = event.PreviousData
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Minimum Cost Progression')
            xlabel(app.UIAxes, 'Epoch')
            ylabel(app.UIAxes, 'Cost')
            app.MinimumCostGaugeLabel = uilabel(app.UIFigure);
            app.MinimumCostGaugeLabel.Text = 'Minimum Cost';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [0 300];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Connections (Settled Solution)')
            xlabel(app.UIAxes2, 'Terminals')
            ylabel(app.UIAxes2, 'Concentrators')
            app.Num_EpochsDropDownLabel = uilabel(app.UIFigure);
            app.Num_EpochsDropDownLabel.Text = 'Num_Epochs';
            app.Num_EpochsDropDown = uidropdown(app.UIFigure);
            app.Num_EpochsDropDown.Items = {'50', '100', '150'};
            app.Num_EpochsDropDown.ItemsData = {'50', '100', '150'};
            app.Num_EpochsDropDown.Value = '50';
            app.MutationProbabilitySliderLabel = uilabel(app.UIFigure);
            app.MutationProbabilitySliderLabel.Text = 'Mutation Probability';
            app.MutationProbabilitySlider = uislider(app.UIFigure);
            app.MutationProbabilitySlider.Limits = [0 0.5];
            app.MutationProbabilitySlider.Value = 0.2;
            app.LearningSpeedButtonGroup = uibuttongroup(app.UIFigure);
            app.LearningSpeedButtonGroup.Title = 'Learning Speed';
            app.Button = uiradiobutton(app.LearningSpeedButtonGroup);
            app.Button.Text = '0.05';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.LearningSpeedButtonGroup);
            app.Button_2.Text = '0.01';
            app.Button_3 = uiradiobutton(app.LearningSpeedButtonGroup);
            app.Button_3.Text = '0.005';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Statistics';
            app.MutationsLabel = uilabel(app.UIFigure);
            app.MutationsLabel.Text = 'Mutations';
            app.MutationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MutationsEditField.Editable = 'off';
            app.IterationsLabel = uilabel(app.UIFigure);
            app.IterationsLabel.Text = 'Iterations       ';
            app.IterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.InfeasibilitiesEditFieldLabel = uilabel(app.UIFigure);
            app.InfeasibilitiesEditFieldLabel.Text = 'Infeasibilities';
            app.InfeasibilitiesEditField = uieditfield(app.UIFigure, 'numeric');
            app.InfeasibilitiesEditField.Editable = 'off';
            app.MinimumCostEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumCostEditFieldLabel.Text = 'Minimum Cost';
            app.MinimumCostEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumCostEditField.Editable = 'off';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Ass_3_code

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end